classdef Depth_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculatedBallPositionLabel    matlab.ui.control.Label
        ErrorDistancemmEditField       matlab.ui.control.NumericEditField
        ErrorDistancemmEditFieldLabel  matlab.ui.control.Label
        ZerrmmEditField                matlab.ui.control.NumericEditField
        ZerrmmEditFieldLabel           matlab.ui.control.Label
        YerrmmEditField                matlab.ui.control.NumericEditField
        YerrmmEditFieldLabel           matlab.ui.control.Label
        XerrmmEditField                matlab.ui.control.NumericEditField
        XerrmmEditFieldLabel           matlab.ui.control.Label
        ZmEditField                    matlab.ui.control.NumericEditField
        ZmEditFieldLabel               matlab.ui.control.Label
        YmEditField                    matlab.ui.control.NumericEditField
        YmEditFieldLabel               matlab.ui.control.Label
        XmEditField                    matlab.ui.control.NumericEditField
        XmEditFieldLabel               matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        CameraBallLocationsLabel       matlab.ui.control.Label
        CameraSettingsLabel            matlab.ui.control.Label
        RowsSlider                     matlab.ui.control.Slider
        RowsSliderLabel                matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        EditFieldRows                  matlab.ui.control.NumericEditField
        ColumnsSlider                  matlab.ui.control.Slider
        ColumnsSliderLabel             matlab.ui.control.Label
        EditFieldColumns               matlab.ui.control.NumericEditField
        BaselinemmSlider               matlab.ui.control.Slider
        BaselinemmSliderLabel          matlab.ui.control.Label
        EditFieldBaseline              matlab.ui.control.NumericEditField
        PixelSizeumSlider              matlab.ui.control.Slider
        PixelSizeumSliderLabel         matlab.ui.control.Label
        EditFieldPixelSize             matlab.ui.control.NumericEditField
        EditFieldFocalLength           matlab.ui.control.NumericEditField
        FocalLengthmmSlider            matlab.ui.control.Slider
        FocalLengthmmSliderLabel       matlab.ui.control.Label
        XmSlider                       matlab.ui.control.Slider
        XmSliderLabel                  matlab.ui.control.Label
        EditFieldX                     matlab.ui.control.NumericEditField
        YmSlider                       matlab.ui.control.Slider
        YmLabel                        matlab.ui.control.Label
        EditFieldY                     matlab.ui.control.NumericEditField
        EditFieldZ                     matlab.ui.control.NumericEditField
        TrueBallPositionLabel          matlab.ui.control.Label
        ZmSlider                       matlab.ui.control.Slider
        ZmSliderLabel                  matlab.ui.control.Label
        RightAxes                      matlab.ui.control.UIAxes
        LeftAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ZmSlider
        function ZmSliderValueChanged(app, event)
            sliderValueZ = app.ZmSlider.Value;
            app.EditFieldZ.Value = sliderValueZ;
        end

        % Value changed function: EditFieldZ
        function EditFieldZValueChanged(app, event)
            editFieldValueZ = app.EditFieldZ.Value;
            if editFieldValueZ > 40
                app.ZmSlider.Value = 40;
                app.EditFieldZ.Value = 40;
            elseif editFieldValueZ < 0
                app.ZmSlider.Value = 0;
                app.EditFieldZ.Value = 0;
            else
                app.ZmSlider.Value = app.EditFieldZ.Value;
            end
        end

        % Value changed function: EditFieldY
        function YmEditFieldValueChanged(app, event)
            editFieldValueY = app.EditFieldY.Value;
            if editFieldValueY > 5
                app.YmSlider.Value = 5;
                app.EditFieldY.Value = 5;
            elseif editFieldValueY < 0
                app.YmSlider.Value = 0;
                app.EditFieldY.Value = 0;
            else 
                app.YmSlider.Value = app.EditFieldY.Value;
            end
        end

        % Value changed function: YmSlider
        function YmSliderValueChanged(app, event)
            sliderValueY = app.YmSlider.Value;
            app.EditFieldY.Value = sliderValueY;
        end

        % Value changed function: EditFieldX
        function EditFieldXValueChanged(app, event)
            editFieldValueX = app.EditFieldX.Value;
            if editFieldValueX > 10
                app.XmSlider.Value = 10;
                app.EditFieldX.Value = 10;
            elseif editFieldValueX < -10
                app.XmSlider.Value = -10;
                app.EditFieldX.Value = -10;
            else 
                app.XmSlider.Value = app.EditFieldX.Value;
            end
        end

        % Value changed function: XmSlider
        function XmSliderValueChanged(app, event)
            sliderValueX = app.XmSlider.Value;
            app.EditFieldX.Value = sliderValueX;
        end

        % Value changed function: FocalLengthmmSlider
        function FocalLengthmmSliderValueChanged(app, event)
            sliderValueFL = app.FocalLengthmmSlider.Value;
            app.EditFieldFocalLength.Value = sliderValueFL;
        end

        % Value changed function: EditFieldFocalLength
        function EditFieldFocalLengthValueChanged(app, event)
            EditFieldFocalLengthValue = app.EditFieldFocalLength.Value;
            if EditFieldFocalLengthValue > 50
                app.FocalLengthmmSlider.Value = 50;
                app.EditFieldFocalLength.Value = 50;
            elseif EditFieldFocalLengthValue < 0
                app.FocalLengthmmSlider.Value = 0;
                app.EditFieldFocalLength.Value = 0;
            else 
                app.FocalLengthmmSlider.Value = app.EditFieldFocalLength.Value;
            end
        end

        % Value changed function: PixelSizeumSlider
        function PixelSizeumSliderValueChanged(app, event)
            sliderValuePS = app.PixelSizeumSlider.Value;
            app.EditFieldPixelSize.Value = sliderValuePS;
        end

        % Value changed function: EditFieldPixelSize
        function EditFieldPixelSizeValueChanged(app, event)
            EditFieldPixelSizeValue = app.EditFieldPixelSize.Value;
            if EditFieldPixelSizeValue > 10
                app.PixelSizeumSlider.Value = 10;
                app.EditFieldPixelSize.Value = 10;
            elseif EditFieldPixelSizeValue < 0
                app.PixelSizeumSlider.Value = 0;
                app.EditFieldPixelSize.Value = 0;
            else 
                app.PixelSizeumSlider.Value = app.EditFieldPixelSize.Value;
            end
        end

        % Value changed function: EditFieldBaseline
        function EditFieldBaselineValueChanged(app, event)
            EditFieldBaselineValue = app.EditFieldBaseline.Value;
            if EditFieldBaselineValue > 500
                app.BaselinemmSlider.Value = 500;
                app.EditFieldBaseline.Value = 500;
            elseif EditFieldBaselineValue < 0
                app.BaselinemmSlider.Value = 0;
                app.EditFieldBaseline.Value = 0;
            else 
                app.BaselinemmSlider.Value = app.EditFieldBaseline.Value;
            end
        end

        % Value changed function: BaselinemmSlider
        function BaselinemmSliderValueChanged(app, event)
            sliderValueBL = app.BaselinemmSlider.Value;
            app.EditFieldBaseline.Value = sliderValueBL;
        end

        % Value changed function: RowsSlider
        function RowsSliderValueChanged(app, event)
            sliderValueRows = app.RowsSlider.Value;
            app.EditFieldRows.Value = sliderValueRows;
        end

        % Value changed function: EditFieldRows
        function EditFieldRowsValueChanged(app, event)
            EditFieldRowsValue = app.EditFieldRows.Value;
            if EditFieldRowsValue > 2000
                app.RowsSlider.Value = 2000;
                app.EditFieldRows.Value = 2000;
            elseif EditFieldRowsValue < 100
                app.RowsSlider.Value = 100;
                app.EditFieldRows.Value = 100;
            else 
                app.RowsSlider.Value = app.EditFieldRows.Value;
            end
        end

        % Value changed function: ColumnsSlider
        function ColumnsSliderValueChanged(app, event)
            sliderValueColumns = app.ColumnsSlider.Value;
            app.EditFieldColumns.Value = sliderValueColumns;
        end

        % Value changed function: EditFieldColumns
        function EditFieldColumnsValueChanged(app, event)
            EditFieldColsValue = app.EditFieldColumns.Value;
            if EditFieldColsValue > 2000
                app.ColumnsSlider.Value = 2000;
                app.EditFieldColumns.Value = 2000;
            elseif EditFieldColsValue < 100
                app.ColumnsSlider.Value = 100;
                app.EditFieldColumns.Value = 100;
            else 
                app.ColumnsSlider.Value = app.EditFieldColumns.Value;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Retrieve Values from Gui
            x_in = app.EditFieldX.Value * 1000;
            y_in = app.EditFieldY.Value * 1000;
            z_in = app.EditFieldZ.Value * 1000;
            b_in = app.EditFieldBaseline.Value; % baseline [mm]
            f_in = app.EditFieldFocalLength.Value; % focal length [mm]
            ps_in = app.EditFieldPixelSize.Value / 1000; % pixel size [mm]
            xNumPix = [app.EditFieldColumns.Value, app.EditFieldRows.Value]; % total number of pixels in x direction of the sensor [px]
            cLeft = xNumPix ./2; % left camera x center [px]
            cRight = xNumPix ./2; % right camera x center [px]

            % set up two cameras with focal length of 6 mm
            camLeft = CentralCamera(...
            'focal', f_in, ...
            'pixel', ps_in, ...
            'resolution', xNumPix,...
            'centre', cLeft, ...
            'name', 'CameraLeft');

            camRight = CentralCamera(...
            'focal', f_in, ...
            'pixel', ps_in, ...
            'resolution', xNumPix,...
            'centre', cRight, ...
            'name', 'CameraRight');
            
            % Set camera positions
            TcamL = SE3(-b_in / 2, 0, 0);
            TcamR = SE3(+b_in / 2, 0, 0);
            camLeft.T = TcamL;
            camRight.T = TcamR;
            
            % Set up point from input vars and project
            PointPos = [x_in, y_in, z_in]';
            Left = camLeft.project(PointPos)
            Right = camRight.project(PointPos)

            % calculate output
            d = (abs((Left(1,1) - cLeft(1,1)) - (Right(1,1) - cRight(1,1))) * ps_in);
            z_out = (((b_in) * f_in) / d);
            x_out = z_out * ((Left(1,1) - cLeft(1,1)) * ps_in) / f_in;
            y_out = z_out * ((Left(2,1) - cLeft(1,2)) * ps_in) / f_in;

            %calculate error [mm]
            Xerr = (x_out - x_in);
            Yerr = (y_out - y_in);
            Zerr = (z_out - z_in);

            % write out to textboxes
            app.XmEditField.Value = x_out / 1000;
            app.YmEditField.Value = y_out / 1000;
            app.ZmEditField.Value = z_out / 1000;
            app.XerrmmEditField.Value = Xerr;
            app.YerrmmEditField.Value = Yerr;
            app.ZerrmmEditField.Value = Zerr;
            app.ErrorDistancemmEditField.Value = Xerr + Yerr + Zerr;
            
            % set plots
            cla(app.LeftAxes);
            hold(app.LeftAxes, 'on');
            scatter(app.LeftAxes, Left(1,1), Left(2,1), 'r', 'filled');
            axis(app.LeftAxes, [(Left(1,1) - 5) (Left(1,1) + 5) (Left(2,1) - 5) (Left(2,1) + 5)]);
            grid(app.LeftAxes, 'on');
            hold(app.LeftAxes, 'off');
            
            cla(app.RightAxes);
            hold(app.RightAxes, 'on');
            scatter(app.RightAxes, Right(1,1), Right(2,1), 'r', 'filled');
            axis(app.RightAxes, [(Right(1,1) - 5) (Right(1,1) + 5) (Right(2,1) - 5) (Right(2,1) + 5)]);
            grid(app.RightAxes, 'on');
            hold(app.RightAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftAxes = uiaxes(app.UIFigure);
            title(app.LeftAxes, 'Left')
            xlabel(app.LeftAxes, 'X')
            ylabel(app.LeftAxes, 'Y')
            zlabel(app.LeftAxes, 'Z')
            app.LeftAxes.XLim = [-10 10];
            app.LeftAxes.YLim = [0 5];
            app.LeftAxes.XTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.RightAxes = uiaxes(app.UIFigure);
            title(app.RightAxes, 'Right')
            xlabel(app.RightAxes, 'X')
            ylabel(app.RightAxes, 'Y')
            zlabel(app.RightAxes, 'Z')
            app.RightAxes.XLim = [-10 10];
            app.RightAxes.YLim = [0 5];
            app.RightAxes.XTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.ZmSliderLabel = uilabel(app.UIFigure);
            app.ZmSliderLabel.Text = 'Z (m)';
            app.ZmSlider = uislider(app.UIFigure);
            app.ZmSlider.Limits = [0 40];
            app.ZmSlider.MajorTicks = [0 10 20 30 40];
            app.ZmSlider.ValueChangedFcn = createCallbackFcn(app, @ZmSliderValueChanged, true);
            app.ZmSlider.Step = 1;
            app.TrueBallPositionLabel = uilabel(app.UIFigure);
            app.TrueBallPositionLabel.Text = 'True Ball Position';
            app.EditFieldZ = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldZ.ValueChangedFcn = createCallbackFcn(app, @EditFieldZValueChanged, true);
            app.EditFieldZ.Value = 1;
            app.EditFieldY = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldY.ValueChangedFcn = createCallbackFcn(app, @YmEditFieldValueChanged, true);
            app.EditFieldY.Value = 1;
            app.YmLabel = uilabel(app.UIFigure);
            app.YmLabel.Text = 'Y (m)';
            app.YmSlider = uislider(app.UIFigure);
            app.YmSlider.Limits = [0 5];
            app.YmSlider.MajorTicks = [0 1 2 3 4 5];
            app.YmSlider.ValueChangedFcn = createCallbackFcn(app, @YmSliderValueChanged, true);
            app.YmSlider.Step = 0.5;
            app.EditFieldX = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldX.ValueChangedFcn = createCallbackFcn(app, @EditFieldXValueChanged, true);
            app.XmSliderLabel = uilabel(app.UIFigure);
            app.XmSliderLabel.Text = 'X (m)';
            app.XmSlider = uislider(app.UIFigure);
            app.XmSlider.Limits = [-10 10];
            app.XmSlider.MajorTicks = [-10 -5 0 5 10];
            app.XmSlider.ValueChangedFcn = createCallbackFcn(app, @XmSliderValueChanged, true);
            app.XmSlider.Step = 0.5;
            app.FocalLengthmmSliderLabel = uilabel(app.UIFigure);
            app.FocalLengthmmSliderLabel.Text = 'Focal Length (mm)';
            app.FocalLengthmmSlider = uislider(app.UIFigure);
            app.FocalLengthmmSlider.Limits = [0 50];
            app.FocalLengthmmSlider.ValueChangedFcn = createCallbackFcn(app, @FocalLengthmmSliderValueChanged, true);
            app.FocalLengthmmSlider.Step = 1;
            app.FocalLengthmmSlider.Value = 1;
            app.EditFieldFocalLength = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFocalLength.ValueChangedFcn = createCallbackFcn(app, @EditFieldFocalLengthValueChanged, true);
            app.EditFieldFocalLength.Value = 1;
            app.EditFieldPixelSize = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldPixelSize.ValueChangedFcn = createCallbackFcn(app, @EditFieldPixelSizeValueChanged, true);
            app.EditFieldPixelSize.Value = 6;
            app.PixelSizeumSliderLabel = uilabel(app.UIFigure);
            app.PixelSizeumSliderLabel.Text = 'Pixel Size (um)';
            app.PixelSizeumSlider = uislider(app.UIFigure);
            app.PixelSizeumSlider.Limits = [0 10];
            app.PixelSizeumSlider.ValueChangedFcn = createCallbackFcn(app, @PixelSizeumSliderValueChanged, true);
            app.PixelSizeumSlider.Step = 0.5;
            app.PixelSizeumSlider.Value = 6;
            app.EditFieldBaseline = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldBaseline.ValueChangedFcn = createCallbackFcn(app, @EditFieldBaselineValueChanged, true);
            app.EditFieldBaseline.Value = 10;
            app.BaselinemmSliderLabel = uilabel(app.UIFigure);
            app.BaselinemmSliderLabel.Text = 'Baseline (mm)';
            app.BaselinemmSlider = uislider(app.UIFigure);
            app.BaselinemmSlider.Limits = [0 500];
            app.BaselinemmSlider.ValueChangedFcn = createCallbackFcn(app, @BaselinemmSliderValueChanged, true);
            app.BaselinemmSlider.Step = 1;
            app.BaselinemmSlider.Value = 10;
            app.EditFieldColumns = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldColumns.ValueChangedFcn = createCallbackFcn(app, @EditFieldColumnsValueChanged, true);
            app.EditFieldColumns.Value = 752;
            app.ColumnsSliderLabel = uilabel(app.UIFigure);
            app.ColumnsSliderLabel.Text = 'Columns';
            app.ColumnsSlider = uislider(app.UIFigure);
            app.ColumnsSlider.Limits = [100 2000];
            app.ColumnsSlider.MajorTicks = [];
            app.ColumnsSlider.ValueChangedFcn = createCallbackFcn(app, @ColumnsSliderValueChanged, true);
            app.ColumnsSlider.Step = 10;
            app.ColumnsSlider.Value = 752;
            app.EditFieldRows = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldRows.ValueChangedFcn = createCallbackFcn(app, @EditFieldRowsValueChanged, true);
            app.EditFieldRows.Value = 480;
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.RowsSliderLabel = uilabel(app.UIFigure);
            app.RowsSliderLabel.Text = 'Rows';
            app.RowsSlider = uislider(app.UIFigure);
            app.RowsSlider.Limits = [100 2000];
            app.RowsSlider.MajorTicks = [100 480 860 1240 1620 2000];
            app.RowsSlider.ValueChangedFcn = createCallbackFcn(app, @RowsSliderValueChanged, true);
            app.RowsSlider.Step = 10;
            app.RowsSlider.Value = 480;
            app.CameraSettingsLabel = uilabel(app.UIFigure);
            app.CameraSettingsLabel.Text = 'Camera Settings';
            app.CameraBallLocationsLabel = uilabel(app.UIFigure);
            app.CameraBallLocationsLabel.Text = 'Camera Ball Locations';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = fullfile(pathToMLAPP, '1084790.png');
            app.CalculateButton.Text = 'Calculate     ';
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmEditFieldLabel = uilabel(app.UIFigure);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XerrmmEditFieldLabel = uilabel(app.UIFigure);
            app.XerrmmEditFieldLabel.Text = 'Xerr (mm)';
            app.XerrmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YerrmmEditFieldLabel = uilabel(app.UIFigure);
            app.YerrmmEditFieldLabel.Text = 'Yerr (mm)';
            app.YerrmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZerrmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZerrmmEditFieldLabel.Text = 'Zerr (mm)';
            app.ZerrmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorDistancemmEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorDistancemmEditFieldLabel.Text = 'Error Distance (mm)';
            app.ErrorDistancemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatedBallPositionLabel = uilabel(app.UIFigure);
            app.CalculatedBallPositionLabel.Text = 'Calculated Ball Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Depth_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end