classdef BallLocator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageUploadPanel            matlab.ui.container.Panel
        UploadImageRightButton      matlab.ui.control.Button
        UploadImageLeftButton       matlab.ui.control.Button
        ImageRight                  matlab.ui.control.UIAxes
        ImageLeft                   matlab.ui.control.UIAxes
        CalculateButton             matlab.ui.control.Button
        CalculatedBallPositonPanel  matlab.ui.container.Panel
        YCrCbLabel                  matlab.ui.control.Label
        ZmEditField_YCrCb           matlab.ui.control.NumericEditField
        ZmmLabel_4                  matlab.ui.control.Label
        YmEditField_YCrCb           matlab.ui.control.NumericEditField
        YmmLabel_4                  matlab.ui.control.Label
        XmEditField_YCrCb           matlab.ui.control.NumericEditField
        XmmLabel_4                  matlab.ui.control.Label
        ZmEditField_RGB             matlab.ui.control.NumericEditField
        ZmmLabel_2                  matlab.ui.control.Label
        YmEditField_RGB             matlab.ui.control.NumericEditField
        YmmLabel_2                  matlab.ui.control.Label
        XmEditField_RGB             matlab.ui.control.NumericEditField
        XmmLabel_2                  matlab.ui.control.Label
        RGBLabel                    matlab.ui.control.Label
        BWLabel                     matlab.ui.control.Label
        ZmEditField_BW              matlab.ui.control.NumericEditField
        ZmmLabel_3                  matlab.ui.control.Label
        YmEditField_BW              matlab.ui.control.NumericEditField
        YmmLabel_3                  matlab.ui.control.Label
        XmEditField_BW              matlab.ui.control.NumericEditField
        XmmLabel_3                  matlab.ui.control.Label
        GrayscaleLabel              matlab.ui.control.Label
        ZmEditField_Grayscale       matlab.ui.control.NumericEditField
        ZmmLabel                    matlab.ui.control.Label
        YmEditField_Grayscale       matlab.ui.control.NumericEditField
        YmmLabel                    matlab.ui.control.Label
        XmEditField_Grayscale       matlab.ui.control.NumericEditField
        XmmLabel                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageLeftButton
        function UploadImageLeftButtonPushed(app, event)
            
            % Search filepath for desired image
            filterSpec = {'*.jpg;*.tif;*.png;*.gif', 'All Image Files'};
            % Open file selection dialog box
            [filename, pathname] = uigetfile(filterSpec, 'Select an Image File');
    
            % Check if the user selected a file (did not cancel)
            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.UIFigure, 'File selection cancelled.', 'Cancelled');
                return;
            end
    
            % Get the full file path & read image
            imageFileL = fullfile(pathname, filename);
            imgL = imread(imageFileL);
    
            % Display the image in Left Image Box
            imshow(imgL, 'Parent', app.ImageLeft); 
    
            % Store the image data in the axes UserData for other functions to use
            app.ImageLeft.UserData = imgL; 
        end

        % Button pushed function: UploadImageRightButton
        function UploadImageRightButtonPushed(app, event)
            % Search filepath for desired image
            filterSpec = {'*.jpg;*.tif;*.png;*.gif', 'All Image Files'};
            % Open file selection dialog box
            [filename, pathname] = uigetfile(filterSpec, 'Select an Image File');
    
            % Check if the user selected a file (did not cancel)
            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.UIFigure, 'File selection cancelled.', 'Cancelled');
                return;
            end
    
            % Get the full file path & read image
            imageFileR = fullfile(pathname, filename);
            imgR = imread(imageFileR);
    
            % Display the image in Left Image Box
            imshow(imgR, 'Parent', app.ImageRight); 
    
            % Store the image data in the axes UserData for other functions to use
            app.ImageRight.UserData = imgR;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % convert all images to respective coloring
            imageLeft_Gray = rgb2gray(app.ImageLeft.UserData);
            imageRight_Gray = rgb2gray(app.ImageRight.UserData);
            imageLeft_BW = imbinarize(app.ImageLeft.UserData);
            imageRight_BW = imbinarize(app.ImageRight.UserData);
            imageLeft_YCbCr = rgb2ycbcr(app.ImageLeft.UserData);
            imageRight_YCbCr = rgb2ycbcr(app.ImageRight.UserData);


            [app.XmEditField_Grayscale.Value, app.YmEditField_Grayscale.Value, ...
                app.ZmEditField_Grayscale.Value] = BallLocator_Circles(imageLeft_Gray, imageRight_Gray);
            [app.XmEditField_BW.Value, app.YmEditField_BW.Value, ...
                app.ZmEditField_BW.Value] = BallLocator_Circles(imageLeft_BW(:,:,1), imageRight_BW(:,:,1));
            [app.XmEditField_YCrCb.Value, app.YmEditField_YCrCb.Value, ...
                app.ZmEditField_YCrCb.Value] = BallLocator_Circles(imageLeft_YCbCr(:,:,1), imageRight_YCbCr(:,:,1));
            [app.XmEditField_RGB.Value, app.YmEditField_RGB.Value, ...
                app.ZmEditField_RGB.Value] = BallLocator_Circles(app.ImageLeft.UserData, app.ImageRight.UserData);

            % read out to
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculatedBallPositonPanel = uipanel(app.UIFigure);
            app.CalculatedBallPositonPanel.Title = 'Calculated Ball Positon';
            app.XmmLabel = uilabel(app.CalculatedBallPositonPanel);
            app.XmmLabel.Text = 'X (mm)';
            app.XmEditField_Grayscale = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.YmmLabel = uilabel(app.CalculatedBallPositonPanel);
            app.YmmLabel.Text = 'Y (mm)';
            app.YmEditField_Grayscale = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.ZmmLabel = uilabel(app.CalculatedBallPositonPanel);
            app.ZmmLabel.Text = 'Z (mm)';
            app.ZmEditField_Grayscale = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.GrayscaleLabel = uilabel(app.CalculatedBallPositonPanel);
            app.GrayscaleLabel.Text = 'Grayscale';
            app.XmmLabel_3 = uilabel(app.CalculatedBallPositonPanel);
            app.XmmLabel_3.Text = 'X (mm)';
            app.XmEditField_BW = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.YmmLabel_3 = uilabel(app.CalculatedBallPositonPanel);
            app.YmmLabel_3.Text = 'Y (mm)';
            app.YmEditField_BW = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.ZmmLabel_3 = uilabel(app.CalculatedBallPositonPanel);
            app.ZmmLabel_3.Text = 'Z (mm)';
            app.ZmEditField_BW = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.BWLabel = uilabel(app.CalculatedBallPositonPanel);
            app.BWLabel.Text = 'B&W';
            app.RGBLabel = uilabel(app.CalculatedBallPositonPanel);
            app.RGBLabel.Text = 'RGB';
            app.XmmLabel_2 = uilabel(app.CalculatedBallPositonPanel);
            app.XmmLabel_2.Text = 'X (mm)';
            app.XmEditField_RGB = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.YmmLabel_2 = uilabel(app.CalculatedBallPositonPanel);
            app.YmmLabel_2.Text = 'Y (mm)';
            app.YmEditField_RGB = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.ZmmLabel_2 = uilabel(app.CalculatedBallPositonPanel);
            app.ZmmLabel_2.Text = 'Z (mm)';
            app.ZmEditField_RGB = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.XmmLabel_4 = uilabel(app.CalculatedBallPositonPanel);
            app.XmmLabel_4.Text = 'X (mm)';
            app.XmEditField_YCrCb = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.YmmLabel_4 = uilabel(app.CalculatedBallPositonPanel);
            app.YmmLabel_4.Text = 'Y (mm)';
            app.YmEditField_YCrCb = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.ZmmLabel_4 = uilabel(app.CalculatedBallPositonPanel);
            app.ZmmLabel_4.Text = 'Z (mm)';
            app.ZmEditField_YCrCb = uieditfield(app.CalculatedBallPositonPanel, 'numeric');
            app.YCrCbLabel = uilabel(app.CalculatedBallPositonPanel);
            app.YCrCbLabel.Text = 'YCrCb';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ImageUploadPanel = uipanel(app.UIFigure);
            app.ImageUploadPanel.Title = 'Image Upload';
            app.ImageLeft = uiaxes(app.ImageUploadPanel);
            app.ImageLeft.XTick = [];
            app.ImageLeft.YTick = [];
            app.ImageLeft.ZTick = [];
            app.ImageRight = uiaxes(app.ImageUploadPanel);
            app.ImageRight.XTick = [];
            app.ImageRight.YTick = [];
            app.ImageRight.ZTick = [];
            app.UploadImageLeftButton = uibutton(app.ImageUploadPanel, 'push');
            app.UploadImageLeftButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageLeftButtonPushed, true);
            app.UploadImageLeftButton.Text = 'Upload Image Left';
            app.UploadImageRightButton = uibutton(app.ImageUploadPanel, 'push');
            app.UploadImageRightButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageRightButtonPushed, true);
            app.UploadImageRightButton.Text = 'Upload Image Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BallLocator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end